classdef Myapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        FunctionsListBoxLabel   matlab.ui.control.Label
        FunctionsListBox        matlab.ui.control.ListBox
        CoefficientSliderLabel  matlab.ui.control.Label
        CoefficientSlider       matlab.ui.control.Slider
        PLOTButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            Coeff=double(get(app.CoefficientSlider,'Value'));
            Option=get(app.FunctionsListBox,'Value');
            t=-pi:0.01:pi;
             switch Option  
                case 'Sin'
                    y=Coeff.*sin(t);
                    disp(y);disp('sin');
                case 'Cos'
                    y=Coeff.*cos(t);
                    disp(y);disp('cos');
                case 'Tan'
                    y= Coeff.*tan(t);
                    disp(y);disp('tan');
            end
            plot(app.UIAxes,y,'r');
        end

        % Value changed function: CoefficientSlider
        function CoefficientSliderValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plotting Graphics with App Designer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FunctionsListBoxLabel = uilabel(app.UIFigure);
            app.FunctionsListBoxLabel.Text = 'Functions';
            app.FunctionsListBox = uilistbox(app.UIFigure);
            app.FunctionsListBox.Items = {'Sin', 'Cos', 'Tan'};
            app.FunctionsListBox.Value = 'Cos';
            app.CoefficientSliderLabel = uilabel(app.UIFigure);
            app.CoefficientSliderLabel.Text = 'Coefficient';
            app.CoefficientSlider = uislider(app.UIFigure);
            app.CoefficientSlider.Limits = [1 5];
            app.CoefficientSlider.ValueChangedFcn = createCallbackFcn(app, @CoefficientSliderValueChanged, true);
            app.CoefficientSlider.Value = 1;
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Myapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end